classdef App1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure                    % UWB Lo...
        UIAxes                 matlab.ui.control.UIAxes            % Range ...
        Panel                  matlab.ui.container.Panel           % Serial...
        Lamp                   matlab.ui.control.Lamp             
        Button                 matlab.ui.control.Button            % Connect
        Button2                matlab.ui.control.Button            % Discon...
        PortList               matlab.ui.control.DropDown          % Option...
        Panel2                 matlab.ui.container.Panel           % Rx Power
        LabelSemicircularGauge matlab.ui.control.Label             % Anchor...
        RxPower1               matlab.ui.control.SemicircularGauge % [-100 0]
        Label                  matlab.ui.control.Label             % Anchor...
        RxPower2               matlab.ui.control.SemicircularGauge % [-100 0]
        Label2                 matlab.ui.control.Label             % Anchor...
        RxPower3               matlab.ui.control.SemicircularGauge % [-100 0]
        UIAxes2                matlab.ui.control.UIAxes            % Node P...
        Panel3                 matlab.ui.container.Panel           % Power ...
        Label3                 matlab.ui.control.Label             % Anchor 1
        Mc1                    matlab.ui.control.SemicircularGauge % [0 1]
        Label4                 matlab.ui.control.Label             % Anchor 2
        Mc2                    matlab.ui.control.SemicircularGauge % [0 1]
        Label5                 matlab.ui.control.Label             % Anchor 3
        Mc3                    matlab.ui.control.SemicircularGauge % [0 1]
        LabelLinearGauge       matlab.ui.control.Label             % Tag He...
        LinearGauge            matlab.ui.control.LinearGauge       % [-5.00...
        Button3                matlab.ui.control.Button            % +
        Button4                matlab.ui.control.Button            % -
        ButtonClearTraj        matlab.ui.control.Button            % Clear
        Panel4                 matlab.ui.container.Panel           % Confid...
        Label6                 matlab.ui.control.Label             % Anchor 1
        CL1                    matlab.ui.control.SemicircularGauge % [0 1]
        Label7                 matlab.ui.control.Label             % Anchor 2
        CL2                    matlab.ui.control.SemicircularGauge % [0 1]
        Label8                 matlab.ui.control.Label             % <Ancho...
        CL3                    matlab.ui.control.SemicircularGauge % [0 1]
    end


    properties (Access = private)
        s                   % Serial Port
        t                   % timer
        ranges = [1 1 1]    % range from each Anchor, (m)
        RXPowers = [0 0 0]  % RX power of each Anchor, (dB)
        Mc = [1 1 1]        % metric for confidence level, (percent)
        CL = [1 1 1]        % confidence level of node
        range1              % range history of Anchor 1 
        range2              % range history of Anchor 2
        range3              % range history of Anchor 3
        time1               % time history of Anchor 1
        time2               % time history of Anchor 2
        time3               % time history of Anchor 3
        posTag              % position history of Tag
        power1              % power history of Anchor 1
        power2              % power history of Anchor 2
        power3              % power history of Anchor 3
        hRange1             % handle of curve 1
        hRange2             % handle of curve 2
        hRange3             % handle of curve 3
        hTimer              % handle of tic-toc timer 
        hTag                % handle of point of Tag
        hTag2               % handle of the second tag (right now, it's the result from trilateration)
        hTraj               % handle of trajectory of Tag
        pf;                                   % the particle filter
    end
    
    properties (Constant, Access = private)
        n_anchors = 3                         % number of anchor nodes
        addresses = {100, 200, 300}           % address for each anchor
        % positions = {{0, 4.06}, {0, -4.27}, {-3.91, 0}}  % positions for each anchor, [X Y], (m)
        % positions = {{-1, 4.06}, {0, -4.27}, {3.5, 1.5}}  % positions for each anchor, [X Y], (m)
        positions = {{-1.70, 3.58, 0}, {-0.37, -3.85, 0}, {1.143, 1.127, 0}}  % positions for each anchor, [X Y], (m); used in lab
        % positions = {{0, 0, 3.16}, {-4.50, -5.97, 3.12}, {5.59, -6.35, 2.71}}; % positions for each anchor, [X Y Z], (m); used in high bay
        err_range = 0.71;                     % error of range measurement (m)
        LEN_SLIDING_WINDOW = 30;              % 30 s
    end
    
    methods (Access = private)
        
        function ReadData(app)
            while app.s.BytesAvailable > 0
                strIn = fscanf(app.s);    % read a line
                % numIn: [address] [range](m) [RX Power](dB)
                if ~isempty(strIn) && strIn(1) == '$'
                    numIn = str2num(strIn(3:end)); %sscanf(strIn(2:end), '%i %f %f');
                    for i = 1:app.n_anchors
%                         if size(numIn) >= 8
                            if app.addresses{i} == numIn(1)
                                if numIn(2) < 50 && numIn(2) > 0 % exception
                                    app.ranges(i) = numIn(2);
                                end
                                app.RXPowers(i) = numIn(3);
                                app.Mc(i) = numIn(6) / numIn(5);
                                IDiff = abs(numIn(8)/64 - numIn(7));
                                if app.Mc(i) < 0.9
                                    if IDiff <= 3.3
                                        PrNLOS = 0;
                                    elseif IDiff < 6.0
                                        PrNLOS = 0.39178*IDiff - 1.31719;
                                    else
                                        PrNLOS = 1.0;
                                    end
                                    app.CL(i) = 1-PrNLOS;
                                else
                                    app.CL(i) = 1;
                                end
                                break;
                            end
%                         end
                    end
                    switch i
                        case 1
                            app.RxPower1.Value = app.RXPowers(1);
                            app.range1 = [app.range1 app.ranges(1)];
                            app.power1 = [app.power1 app.RXPowers(1)];
                            app.hRange1.YData = app.range1 - app.err_range;
                            app.time1 = [app.time1 toc(app.hTimer)];
                            app.hRange1.XData = app.time1;
                            app.Mc1.Value = app.Mc(1);
                            app.CL1.Value = app.CL(1);
                        case 2
                            app.RxPower2.Value = app.RXPowers(2);
                            app.range2 = [app.range2 app.ranges(2)];
                            app.power2 = [app.power2 app.RXPowers(2)];
                            app.hRange2.YData = app.range2 - app.err_range;
                            app.time2 = [app.time2 toc(app.hTimer)];
                            app.hRange2.XData = app.time2;
                            app.Mc2.Value = app.Mc(2);
                            app.CL2.Value = app.CL(2);
                        case 3
                            app.RxPower3.Value = app.RXPowers(3);
                            app.range3 = [app.range3 app.ranges(3)];
                            app.power3 = [app.power3 app.RXPowers(3)];
                            app.hRange3.YData = app.range3 - app.err_range;
                            app.time3 = [app.time3 toc(app.hTimer)];
                            app.hRange3.XData = app.time3;
                            app.Mc3.Value = app.Mc(3);
                            app.CL3.Value = app.CL(3);
                            
                            % Sliding Window
                            xMax = max([app.hRange1.XData app.hRange2.XData app.hRange3.XData]);
                            yDataAll = [app.hRange1.YData app.hRange2.YData app.hRange3.YData];
                            yMin = min(yDataAll);
                            yMax = max(yDataAll);
                            axis(app.UIAxes, [max(0, xMax-app.LEN_SLIDING_WINDOW) xMax yMin yMax]);
                            % TODO
                            % Calculate when a 3-range measurement is completed
                            if length(app.ranges) == 3
                                % Trilateration
                                pTag = trilateration([app.positions{1}{1} app.positions{1}{2} app.positions{1}{3}], app.ranges(1)-app.err_range, ...
                                                     [app.positions{2}{1} app.positions{2}{2} app.positions{2}{3}], app.ranges(2)-app.err_range, ...
                                                     [app.positions{3}{1} app.positions{3}{2} app.positions{3}{3}], app.ranges(3)-app.err_range);
                                app.hTag2.XData = real(pTag(1)); % Do low-pass filter if you want
                                app.hTag2.YData = real(pTag(2)); % Do low-pass filter if you want
                                app.LinearGauge.Value = real(pTag(3));
                                app.posTag = [app.posTag; pTag];
                                %

                                % Or Particle filter
                                % TODO: -err_range
                                  y = [app.ranges(1)-app.err_range; app.ranges(2)-app.err_range; app.ranges(3)-app.err_range];
                                  [xh, app.pf] = particle_filter(app.pf.sys, y, app.pf, 'systematic_resampling');
                                  
                                  app.hTag.XData = xh(1);
                                  app.hTag.YData = xh(2);
                                  if length(xh) >= 3
                                      app.hTag.ZData = xh(3);
                                  end
                                  disp([app.hTag.XData app.hTag.YData app.hTag.ZData]);

                                % Position History
                                app.hTraj.XData = [app.hTraj.XData app.hTag.XData];
                                app.hTraj.YData = [app.hTraj.YData app.hTag.YData];
                                
                            end

                    end
                    

                end
            end
        end

    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             % timer set up
%             time_step = 0.06;
%             app.t = timer;
%             app.t.TimerFcn = @(~, ~)app.SendData;
%             app.t.ExecutionMode = 'fixedRate';
%             app.t.Period = time_step;
%             app.t.BusyMode = 'drop';
            % timer setup
            app.hTimer = tic;
            
            % looking for available serial ports (Instrument Control Toolbox required)
            p = instrhwinfo('serial');
            app.PortList.Items = p.AvailableSerialPorts;
            
            % prepare range plot
            pCurves = plot(app.UIAxes, [0], [0], 'b', [0], [0], 'g', [0], [0], 'k');
            box(app.UIAxes, 'on');
            app.hRange1 = pCurves(1);
            app.hRange2 = pCurves(2);
            app.hRange3 = pCurves(3);
            app.hRange1.XData = [];
            app.hRange1.YData = [];
            app.hRange2.XData = [];
            app.hRange2.YData = [];
            app.hRange3.XData = [];
            app.hRange3.YData = [];
            
            % prepare position plot
            hPosition = plot(app.UIAxes2, app.positions{1}{1}, app.positions{1}{2}, 'bo', ...
                                          app.positions{2}{1}, app.positions{2}{2}, 'go', ...
                                          app.positions{3}{1}, app.positions{3}{2}, 'ko', ...
                                          0, 0, 'r.', ...
                                          0, 0, 'm', ...
                                          0, 0, 'c^');
            axis(app.UIAxes2, 'equal');
            % axis(app.UIAxes2, [-6 6 -8 1]);
            axis(app.UIAxes2, [-5 5 -5 5]);
%            axis(app.UIAxes2, [-20 20 -20 20]);
            box(app.UIAxes2, 'on');
            set(hPosition(1), 'MarkerSize', 6);
            set(hPosition(2), 'MarkerSize', 6);
            set(hPosition(3), 'MarkerSize', 6);
            set(hPosition(4), 'MarkerSize', 9);
            set(hPosition(6), 'MarkerSize', 9);
            app.hTag = hPosition(4);
            app.hTraj = hPosition(5);
            app.hTag2 = hPosition(6);
            
            app.pf = pf_init_2D();
        end

        % Button button pushed function
        function BConButtonPushed(app)
            % on connect open serial port, start timer and change lamp color to green
            if isempty(app.s) || strcmp(app.s.Status, 'closed')
                app.s = serial(app.PortList.Value);
                % call ReadData function if buffer contains uint16 (2 bytes)
                app.s.BaudRate = 115200;
                % app.s.BytesAvailableFcnCount = 19;
                app.s.BytesAvailableFcnMode = 'Terminator';
                app.s.BytesAvailableFcn = @(~, ~)app.ReadData;
                fopen(app.s);
                % start(app.t);
                app.Lamp.Color = [0.00,1.00,0.00];
            end
        end

        % Button2 button pushed function
        function BDisconButtonPushed(app)
            % on disconnect stop timer, close serial port and change lamp color to red
            if strcmp(app.s.Status, 'open')
                % stop(app.t);
                stopasync(app.s)
                fclose(app.s);
                app.Lamp.Color = [1.00,0.00,0.00];
            end
        end

        % UIFigure close request function
        function UIFigureCloseRequest(app)
            filename = [datestr(now) '.mat'];
            filename(filename == ':') = '-';
            A1 = {};
            A1.range = app.range1;
            A1.time = app.time1;
            A1.power = app.power1;
            A2 = {};
            A2.range = app.range2;
            A2.time = app.time2;
            A2.power = app.power2;
            A3 = {};
            A3.range = app.range3;
            A3.time = app.time3;
            A3.power = app.power3;
            T = {};
            T.time = app.hRange1.XData;
            T.pos = app.posTag;
            save(filename, 'A1', 'A2', 'A3', 'T');
            closereq
        end

        % Button3 button pushed function
        function ZoomInPushed(app)
            axis(app.UIAxes2, [-6 6 -8 1]); % +
        end

        % Button4 button pushed function
        function ZoomOutPushed(app)
            axis(app.UIAxes2, [-20 20 -15 15]); % -
        end

        % ButtonClearTraj button pushed function
        function ClearTrajPushed(app)
            app.hTraj.XData = [];
            app.hTraj.YData = [];
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UWB Localization';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest);
            setAutoResize(app, app.UIFigure, true)
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Range from Anchors');
            xlabel(app.UIAxes, 'time (s)');
            ylabel(app.UIAxes, 'range (m)');
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Serial Port';
            app.Panel.Units = 'pixels';
            app.Lamp = uilamp(app.Panel);
            app.Button = uibutton(app.Panel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @BConButtonPushed);
            app.Button.Text = 'Connect';
            app.Button2 = uibutton(app.Panel, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @BDisconButtonPushed);
            app.Button2.Text = 'Disconnect';
            app.PortList = uidropdown(app.Panel);
            app.Panel2 = uipanel(app.UIFigure);
            app.Panel2.Title = 'Rx Power';
            app.Panel2.Units = 'pixels';
            app.LabelSemicircularGauge = uilabel(app.Panel2);
            app.LabelSemicircularGauge.Text = 'Anchor 1 (dB)';
            app.RxPower1 = uigauge(app.Panel2, 'semicircular');
            app.RxPower1.Limits = [-100 0];
            app.Label = uilabel(app.Panel2);
            app.Label.Text = 'Anchor 2 (dB)';
            app.RxPower2 = uigauge(app.Panel2, 'semicircular');
            app.RxPower2.Limits = [-100 0];
            app.Label2 = uilabel(app.Panel2);
            app.Label2.Text = 'Anchor 3 (dB)';
            app.RxPower3 = uigauge(app.Panel2, 'semicircular');
            app.RxPower3.Limits = [-100 0];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Node Positions');
            xlabel(app.UIAxes2, 'X (m)');
            ylabel(app.UIAxes2, 'Y (m)');
            app.Panel3 = uipanel(app.UIFigure);
            app.Panel3.Title = 'Power Metric';
            app.Panel3.Units = 'pixels';
            app.Label3 = uilabel(app.Panel3);
            app.Label3.Text = 'Anchor 1';
            app.Mc1 = uigauge(app.Panel3, 'semicircular');
            app.Mc1.Limits = [0 1];
            app.Mc1.Value = 1;
            app.Label4 = uilabel(app.Panel3);
            app.Label4.Text = 'Anchor 2';
            app.Mc2 = uigauge(app.Panel3, 'semicircular');
            app.Mc2.Limits = [0 1];
            app.Mc2.Value = 1;
            app.Label5 = uilabel(app.Panel3);
            app.Label5.Text = 'Anchor 3';
            app.Mc3 = uigauge(app.Panel3, 'semicircular');
            app.Mc3.Limits = [0 1];
            app.Mc3.Value = 1;
            app.LabelLinearGauge = uilabel(app.UIFigure);
            app.LabelLinearGauge.Text = 'Tag Height (m)';
            app.LinearGauge = uigauge(app.UIFigure, 'linear');
            app.LinearGauge.Limits = [-0.5 3];
            app.LinearGauge.MajorTicks = [-0.5 0 0.5 1 1.5 2 2.5 3];
            app.Button3 = uibutton(app.UIFigure, 'push');
            app.Button3.ButtonPushedFcn = createCallbackFcn(app, @ZoomInPushed);
            app.Button3.Text = '+';
            app.Button4 = uibutton(app.UIFigure, 'push');
            app.Button4.ButtonPushedFcn = createCallbackFcn(app, @ZoomOutPushed);
            app.Button4.Text = '-';
            app.ButtonClearTraj = uibutton(app.UIFigure, 'push');
            app.ButtonClearTraj.ButtonPushedFcn = createCallbackFcn(app, @ClearTrajPushed);
            app.ButtonClearTraj.Text = 'Clear';
            app.Panel4 = uipanel(app.UIFigure);
            app.Panel4.Title = 'Confidence Level of Line-Of-Sight';
            app.Panel4.Units = 'pixels';
            app.Label6 = uilabel(app.Panel4);
            app.Label6.Text = 'Anchor 1';
            app.CL1 = uigauge(app.Panel4, 'semicircular');
            app.CL1.Limits = [0 1];
            app.CL1.Value = 1;
            app.Label7 = uilabel(app.Panel4);
            app.Label7.Text = 'Anchor 2';
            app.CL2 = uigauge(app.Panel4, 'semicircular');
            app.CL2.Limits = [0 1];
            app.CL2.Value = 1;
            app.Label8 = uilabel(app.Panel4);
            app.Label8.Text = '<Anchor 3>';
            app.CL3 = uigauge(app.Panel4, 'semicircular');
            app.CL3.Limits = [0 1];
            app.CL3.Value = 1;
        end
    end

    methods (Access = public)

        % Construct app
        function app = App1()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end